classdef AliasingScore < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AliasingScoreApp            matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        AliasingScoreTab            matlab.ui.container.Tab
        AliasingScoreAxes           matlab.ui.control.UIAxes
        SimulationAxes              matlab.ui.control.UIAxes
        SimulateButton              matlab.ui.control.Button
        StopTimeEditFieldLabel      matlab.ui.control.Label
        StopTimeEditField           matlab.ui.control.NumericEditField
        GroupDropDownLabel          matlab.ui.control.Label
        GroupDropDown               matlab.ui.control.DropDown
        PercentageEditFieldLabel    matlab.ui.control.Label
        PercentageEditField         matlab.ui.control.NumericEditField
        LowerBoundSlider            matlab.ui.control.Slider
        UpperBoundSlider            matlab.ui.control.Slider
        ResponseDropDownLabel       matlab.ui.control.Label
        ResponseDropDown            matlab.ui.control.DropDown
        ShowAliasingScoreForCurrentResponseOnlyCheckBox  matlab.ui.control.CheckBox
        UseDataTimesForAliasingScoreCheckBox  matlab.ui.control.CheckBox
        ZoomCheckBox                matlab.ui.control.CheckBox
        UseSensitivityForAliasingScoreCheckBox  matlab.ui.control.CheckBox
        ComponentInfoTab            matlab.ui.container.Tab
        ComponentInfoTable          matlab.ui.control.Table
        DataInfoTab                 matlab.ui.container.Tab
        DataInfoTable               matlab.ui.control.Table
        IncludeAllInAliasingScoreButton  matlab.ui.control.Button
        DeselectAllResponsesButton  matlab.ui.control.Button
        DataTableTab                matlab.ui.container.Tab
        DataTable                   matlab.ui.control.Table
    end


    properties (Access = private)
        SimBiologyModel         % SimBiology model
        SimFunction             % SimFunction
        CachedSimulationData    % Cached simulation data
        GroupVariableName       % Name of group variable
        IndependentVariableName % Name of independent variable
    end

    methods (Access = private)
    
        function [doses, doseTargets] = getDoses(app)
            % Get grouped data from Data Table
            groupedDataObj = groupedData(app.DataTable.Data);
            groupedDataObj.Properties.GroupVariableName = app.GroupVariableName;
            groupedDataObj.Properties.IndependentVariableName = app.IndependentVariableName;
            
            tfDefinesDose = app.DataInfoTable.Data{:,3};
            if any(tfDefinesDose)
                % Use grouped data to get doses and dose targets
                doseLabels  = app.DataInfoTable.Data{tfDefinesDose, 1};
                doseTargets = cellstr(app.DataInfoTable.Data{tfDefinesDose, 2});
                % Create dosing information.            
                doses = createDoses(groupedDataObj, doseLabels);
                groupSelector = categorical(unique(groupedDataObj.(app.GroupVariableName))) == ...
                    categorical({app.GroupDropDown.Value});
                doses = doses(groupSelector, :);
                for i = 1:numel(doses)
                    set(doses(i), 'TargetName', doseTargets{i});
                end
            else 
                doseTargets = [];
                doses = [];
            end
        end
        
        function evaluateSimFunction(app, dosing, tfIncludeComponentInScore)            
            % Generate samples to scan over:
            componentValues = repmat(app.ComponentInfoTable.Data{:, 3}', 3, 1);
            componentIdx    = find(tfIncludeComponentInScore)';
            numComponents   = numel(componentIdx);
            
            phi = componentValues;
            
            for i = 1:numComponents
                
                % Generate samples to scan over.
                phi(:, componentIdx(i)) = componentValues(1, componentIdx(i)) * ...
                    linspace(1-app.PercentageEditField.Value, ...
                    1+app.PercentageEditField.Value, 3);
                
                % Store difference of component value variation.
                app.CachedSimulationData.ComponentDiff(i) = ...
                    phi(3, componentIdx(i)) - phi(1, componentIdx(i));

                % Simulate samples:
                if isempty(dosing)
                    app.CachedSimulationData.Simulations{i} = ...
                        app.SimFunction(phi, app.StopTimeEditField.Value, [], ...
                        linspace(0, app.StopTimeEditField.Value, 1000));
                else
                    app.CachedSimulationData.Simulations{i} = ...
                        app.SimFunction(phi, app.StopTimeEditField.Value, ...
                        getTable(dosing), linspace(0, app.StopTimeEditField.Value, 1000));
                end
                
                % Reset parameter values for current column.
                phi(:, componentIdx(i)) = componentValues(1, componentIdx(i));
                
            end
        end
        
        function aliasingScore = computeAliasingScore(app)
            
            % Get diagnosis interval for Aliasing Score
            diagnosisInterval = [app.LowerBoundSlider.Value, ...
                app.UpperBoundSlider.Value];
            
            % Initialize Aliasing Score
            numComponents = numel(app.CachedSimulationData.ComponentNames);
            aliasingScore = 100*ones(numComponents);
                
            % Get nominal response:
            sd = app.CachedSimulationData.Simulations{1}(2);
                        
            % Get times/time interval for Aliasing Score.
            if app.UseDataTimesForAliasingScoreCheckBox.Value
                % Get measurement times for currently select group.
                timeColIdx = ismember(app.DataTable.Data.Properties.VariableNames, ...
                    app.IndependentVariableName);
                timeRowIdx = categorical(app.DataTable.Data.(app.GroupVariableName)) == ...
                    categorical({app.GroupDropDown.Value});
                time = app.DataTable.Data{timeRowIdx, timeColIdx};
                times = time(time >= diagnosisInterval(1) & ...
                             time <= diagnosisInterval(2));
            else
                tfIncludeData = sd.Time >= diagnosisInterval(1) & ...
                                sd.Time <= diagnosisInterval(2);
                times = sd.Time(tfIncludeData);
            end
            
            if isempty(times)
                return;
            end
            
            if app.UseSensitivityForAliasingScoreCheckBox.Value
                % Resample SimData at required times.
                sd = sd.resample(times);
                [~, R] = getsensmatrix(sd);
            else
                R = app.computeSensitivities(times);
            end
            
            if app.ShowAliasingScoreForCurrentResponseOnlyCheckBox.Value
                [~, responseIdx] = ismember({app.ResponseDropDown.Value}, ...
                    app.CachedSimulationData.ResponseNames);
            else
                responseIdx = 1:numel(app.CachedSimulationData.ResponseNames);
            end
            
            % Compute normalization factor for sensitivities
            numResponses = numel(responseIdx);
            maxR = nan(numResponses, numComponents);
            for i = 1:numComponents
                for k = 1:numResponses
                    maxR(k,i) = max(abs(R(:,responseIdx(k),i)));
                    if maxR(k,i) < 1e-16
                        maxR(k,i) = 1;
                    end
                end
            end
            
            % Compute Aliasing Scores for all pairwise component combinations
            for i = 1:numComponents
                for j = (i+1):numComponents
                    for k = 1:numResponses
                        aliasingMetric = max(abs(abs(R(:, responseIdx(k), i)/ maxR(k, i)) - ...
                                             abs(R(:, responseIdx(k), j)/ maxR(k, j))));
                        if k == 1 || app.ShowAliasingScoreForCurrentResponseOnlyCheckBox.Value
                            aliasingScore(i,j) = (1-aliasingMetric)*100;
                        else
                            aliasingScore(i,j) = min(aliasingScore(i,j), (1-aliasingMetric)*100);
                        end
                    end
                    aliasingScore(j,i) = aliasingScore(i,j);
                end
            end            
        
        end
        
        function plot(app, aliasingScore)
            
            if isempty(app.CachedSimulationData)
                return;
            end
            
            uiAxesForScorePlot = app.AliasingScoreAxes;
            
            uiAxesForSimulationPlot = app.SimulationAxes;
            uiAxesForSimulationPlot.NextPlot = 'replace';
            
            % Reorder Aliasing Scores to cluster aliasing parameters
            numComponents = numel(app.CachedSimulationData.ComponentNames);
            if false % For clustering aliasing parameters around around diagnoal
                clusterCutoff = quantile(aliasingScore(~eye(numComponents)), 0.8);
                copyScoreMatrix = aliasingScore;
                copyScoreMatrix(aliasingScore < clusterCutoff) = 0;
                componentIdx = symrcm(copyScoreMatrix);
                aliasingScore = aliasingScore(componentIdx, componentIdx);
            else
                componentIdx = 1:numComponents;
            end
            
            % Plot Aliasing Score
            image(uiAxesForScorePlot, aliasingScore);
            % Setup colormap for Aliasing Score
            cmap = zeros(100,3); 
            yellowPosition = 75;
            cmap(1:(100-yellowPosition),1) = linspace(.9,1,100-yellowPosition);
            cmap(1:(100-yellowPosition),2) = linspace(0,1,100-yellowPosition);
            cmap((101-yellowPosition):100,1) = fliplr(linspace(0,1,yellowPosition));
            cmap((101-yellowPosition):100,2) = fliplr(linspace(.8,1,yellowPosition));
            cmap = flipud(cmap);
            colormap(uiAxesForScorePlot, cmap);
            colorbar(uiAxesForScorePlot, ...
                'Ticks', [1, 101], ...
                'TickLabels', {'low', 'high'});
            % Setup axes for Aliasing Score
            uiAxesForScorePlot.YTick = 1:numComponents;
            uiAxesForScorePlot.YTickLabel = ...
                app.CachedSimulationData.ComponentNames(componentIdx);
            uiAxesForScorePlot.YLabel.Interpreter = 'none';
            uiAxesForScorePlot.YLim = [0.5, numComponents+0.5];
            uiAxesForScorePlot.XLim = [0.5, numComponents+0.5];
            uiAxesForScorePlot.XTick = 1:numComponents;
            uiAxesForScorePlot.XTickLabel = ...
                app.CachedSimulationData.ComponentNames(componentIdx);
            uiAxesForScorePlot.XTickLabelRotation = 40;
            uiAxesForScorePlot.XLabel.Interpreter = 'none';
            uiAxesForScorePlot.TickLabelInterpreter = 'none';
            uiAxesForScorePlot.Title.String = 'Aliasing Score';

            
            % Get default colors for sensitivities.
            defaultColors = get(groot, 'defaultAxesColorOrder');
            numDefaultColors = size(defaultColors, 1); 
            
            % Get measurement times
            measurementTimeColIdx = ismember(app.DataTable.Data.Properties.VariableNames, ...
                app.IndependentVariableName);
            measurementTimeRowIdx = categorical(app.DataTable.Data.(app.GroupVariableName)) == ...
                categorical({app.GroupDropDown.Value});
            measurementTimes = app.DataTable.Data{measurementTimeRowIdx, measurementTimeColIdx};

            % Get response index
            [~, responseIdx] = ismember(app.ResponseDropDown.Value, app.ResponseDropDown.Items);          
            stopTime = app.CachedSimulationData.StopTime;

            for k = 1:numComponents
                modk = mod(k, numDefaultColors);
                if modk == 0
                    modk = numDefaultColors;
                end
                color = defaultColors(modk,:);
                if app.UseSensitivityForAliasingScoreCheckBox.Value
                    sd = app.CachedSimulationData.Simulations{1}(2);
                    [time, R] = getsensmatrix(sd);
                    maxR = max(abs(R(:,responseIdx,componentIdx(k))));
                    if maxR <= 1e-16
                        maxR = 1;
                    end
                    plot(uiAxesForSimulationPlot, time, -1+3*(numComponents-k) + ...
                                2*abs(R(:,responseIdx,componentIdx(k))/maxR), ...
                                'Linewidth', 2, 'Color', color);
                    uiAxesForSimulationPlot.NextPlot = 'add';
                else
                    sd = app.CachedSimulationData.Simulations{componentIdx(k)};
                    sd = resample(sd, sd(2).Time);
                    for i = [1, 3]
                        
                        diffResponse = sd(i).Data(:, responseIdx) - sd(2).Data(:, responseIdx);
                        maxDiff = max(abs(diffResponse));
                        if maxDiff < 1e-16
                            maxDiff = 1;
                        end
                        plot(uiAxesForSimulationPlot, sd(i).Time, 3*(numComponents-k) + ...
                            (diffResponse/maxDiff), ...
                            'Linewidth', 2, 'Color', color);
                        uiAxesForSimulationPlot.NextPlot = 'add';
                    end
                end            
                
                plot(uiAxesForSimulationPlot, [measurementTimes, measurementTimes], ...
                    [-1,1]+3*(numComponents-k), 'k-');
                plot(uiAxesForSimulationPlot, [0, stopTime], [1.5,1.5]+3*(numComponents-k), 'k-');
                plot(uiAxesForSimulationPlot, [0, stopTime], -[1.5,1.5]+3*(numComponents-k), 'k-');
                plot(uiAxesForSimulationPlot, [0, stopTime], [1,1]+3*(numComponents-k), 'k:');
                plot(uiAxesForSimulationPlot, [0, stopTime], -[1,1]+3*(numComponents-k), 'k:');
            end

            % Setup axes for simulation plot
            uiAxesForSimulationPlot.YTickMode = 'manual';
            uiAxesForSimulationPlot.YTick = 0:3:3*(numComponents-1);
            uiAxesForSimulationPlot.YTickLabel = ...
                flipud(app.CachedSimulationData.ComponentNames(componentIdx));
            uiAxesForSimulationPlot.YTickLabelRotation = 60;
            uiAxesForSimulationPlot.YLim = [-1.5, 1.5+3*(numComponents-1)];
            legend(uiAxesForSimulationPlot, 'off');
            if app.UseSensitivityForAliasingScoreCheckBox.Value
                uiAxesForSimulationPlot.Title.String = 'Normalized Sensitivities';
            else
                uiAxesForSimulationPlot.Title.String = 'Normalized Responses';
            end
            
            % Get diagnosis interval
            diagnosisInterval = [app.LowerBoundSlider.Value, ...
                app.UpperBoundSlider.Value];
            if app.ZoomCheckBox.Value
                % Zoom time axis to diagnosis interval
                if diff(diagnosisInterval) > 0
                    uiAxesForSimulationPlot.XLim = diagnosisInterval;
                else
                    uiAxesForSimulationPlot.XLim = diagnosisInterval + [-1e-6, 1e-6];
                end
            else
                % Plot diagnosis interval as shaded area
                fill(uiAxesForSimulationPlot, [diagnosisInterval, fliplr(diagnosisInterval)], ...
                [app.SimulationAxes.YLim(1),app.SimulationAxes.YLim(1), ...
                app.SimulationAxes.YLim(2), app.SimulationAxes.YLim(2)],...
                defaultColors(1,:), 'FaceColor', defaultColors(1,:), 'FaceAlpha', 0.1);
                app.SimulationAxes.XLim = [0, stopTime];
            end
            
            uiAxesForSimulationPlot.TickLabelInterpreter = 'none';
            uiAxesForSimulationPlot.XLabel.String = 'Time';

        end
           
        function setControlsForSimulationRequired(app, onOff)
            % Disable controls
            app.LowerBoundSlider.Enable = onOff;
            app.UpperBoundSlider.Enable = onOff;
            app.ResponseDropDown.Enable = onOff;
            app.ZoomCheckBox.Enable     = onOff;
            app.UseDataTimesForAliasingScoreCheckBox.Enable = onOff;
            app.ShowAliasingScoreForCurrentResponseOnlyCheckBox.Enable = onOff;
        end
        
        function R = computeSensitivities(app, times)
            % Compute sensitivities of responses with respect to selected components
            numComponents = numel(app.CachedSimulationData.ComponentNames);
            numResponses  = numel(app.CachedSimulationData.ResponseNames);
            
            % Initialize sensitivity matrix
            R = nan(numel(times), numResponses, numComponents);
            
            for i = 1:numComponents
                sd = resample(app.CachedSimulationData.Simulations{i}, times);
                dC = power(app.CachedSimulationData.ComponentDiff(i), 2);
                for k = 1:numResponses
                    % Compute second order finite difference approximation of sensitivities
                    R(:, k, i) = sd(3).Data(:,k) - sd(1).Data(:,k);
                    R(:, k, i) = R(:, k, i) / dC;
                end
            end
        end


        function validateInputs(app, sbModel, dataTable)
            if ~isa(sbModel, 'SimBiology.Model') || ~isscalar(sbModel)
                delete(app);
                error('The first input argument must be a valid scalar SimBiology model.');
            end
            nonConstComponents = sbioselect(sbModel, ...
                'Type', {'species', 'parameter', 'compartment'});
            if isempty(nonConstComponents) 
                delete(app);
                error('The model must contain at least one nonconstant species, parameter, or compartment.');
            end
            if ~isa(dataTable, 'table')
                delete(app);
                error('The second input argument must be of type table.');
            end
            numVariableNames = numel(dataTable.Properties.VariableNames);
            if numVariableNames < 3
                delete(app);
                error(['The data table must contain at least three columns specifying ', ...
                    'the groups, the time and at least one data column.']);
            end
            if isempty(dataTable) 
                delete(app);
                error('The data table must contain at least one row.');
            end

        end
        
        
        function groupVariableNameChanged(app)
            % Get all identifiers of the groups from the Data Table
            tfIdx = ismember(app.DataTable.ColumnName, ...
                app.GroupVariableName);
            groupValues = unique(app.DataTable.Data{:,tfIdx});
            % Standardize identifiers to a cell array
            if ~iscell(groupValues)
                groupValues = groupValues(~isnan(groupValues));
                groupValues = arrayfun(@(x)char(string(x)), groupValues, ...
                    'UniformOutput', false);
            end
            % Populate Group drop down menu in Aliasing Score tab
            app.GroupDropDown.Items = groupValues;
            app.GroupDropDown.Value = groupValues{1};
            
            % Changes to the group variable require resetting the SimFunction
            app.ResetSimFunction();
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sbModel, dataTable)
            
            if nargin < 3
                uialert(app.AliasingScoreApp, ['Open the Aliasing Score App on the MATLAB Command Line ' ...
                    'using the signature AliasingScore(model, data). ', ...
                    'The second input argument, data, must be a table.'], ...
                    'Missing input arguments', 'CloseFcn', @(f,event)delete(f)); 
                
                return;
            end
            
            % Validate input arguments
            app.validateInputs(sbModel, dataTable);
            
            % Store reference to SimBiology Model
            app.SimBiologyModel = sbModel;
            
            % Store data table
            variableNames            = dataTable.Properties.VariableNames';
            app.DataTable.Data       = dataTable;
            app.DataTable.ColumnName = variableNames;

            % Initialize Parameter Info table with all non-constant quantity components
            nonConstComponents = sbioselect(app.SimBiologyModel, ...
                'Type', {'species', 'parameter', 'compartment'});
            % Get qualified names of quantity components
            DQNs      = get(nonConstComponents, {'DisplayQualifiedName'});
            % Get indicators of types of quantity components
            tfParam   = arrayfun(@(obj)isa(obj, 'SimBiology.Parameter'), nonConstComponents);
            tfSpecies = arrayfun(@(obj)isa(obj, 'SimBiology.Species'), nonConstComponents);
            tfComp    = arrayfun(@(obj)isa(obj, 'SimBiology.Compartment'), nonConstComponents);
            % Get values of quantity components
            values            = nan(size(DQNs));
            valCell           = get(nonConstComponents(tfParam), {'Value'});
            values(tfParam)   = [valCell{:}]';
            valCell           = get(nonConstComponents(tfSpecies), {'InitialAmount'});
            values(tfSpecies) = [valCell{:}]';
            valCell           = get(nonConstComponents(tfComp), {'Capacity'});
            values(tfComp)    = [valCell{:}]';
            % Get types of quantity components
            types            = cell(size(DQNs));
            types(tfParam)   = repmat({'Parameter'}, sum(tfParam), 1);
            types(tfSpecies) = repmat({'Species'}, sum(tfSpecies), 1);
            types(tfComp)    = repmat({'Compartment'}, sum(tfComp), 1);
            % Initialize column indicating whether a component is included in aliasing score or not
            includeInScan    = false(size(DQNs));
            % Fill Parameter Info table
            app.ComponentInfoTable.Data = table(types, DQNs, values, includeInScan);

            % Initialize Data Info table
            numDataInfoRows = numel(variableNames);
            
            componentNameSelection = categorical(repmat({'<unknown>'}, numDataInfoRows, 1), ...
                [{'<unknown>', '<group variable>', '<independent variable>'}, DQNs']);
            app.DataInfoTable.Data = table( ...
                variableNames, ...                              % data names
                componentNameSelection, ...  % model component names
                false(numDataInfoRows, 1), ...                  % defines dose (yes/no)
                false(numDataInfoRows, 1));                     % include in aliasing score (yes/no)
            
                
            % Convert data table to grouped data to infer
            %  - group variable name 
            %  - independent variable name
            gData = groupedData(dataTable);
            if isempty(gData.Properties.GroupVariableName) && ...
                    isempty(gData.Properties.IndependentVariableName)
                app.GroupVariableName = variableNames{1};
                app.IndependentVariableName = variableNames{2};
            elseif ~isempty(gData.Properties.GroupVariableName) && ...
                    isempty(gData.Properties.IndependentVariableName)
                app.GroupVariableName = gData.Properties.GroupVariableName;
                indepVarIdx = find(~ismember(variableNames, gData.Properties.GroupVariableName), 1);
                app.IndependentVariableName = variableNames{indepVarIdx};
            elseif isempty(gData.Properties.GroupVariableName) && ...
                    ~isempty(gData.Properties.IndependentVariableName)
                groupVarIdx = find(~ismember(variableNames, gData.Properties.IndependentVariableName), 1);
                app.GroupVariableName = variableNames{groupVarIdx};
                app.IndependentVariableName = gData.Properties.IndependentVariableName;
            else
                app.GroupVariableName = gData.Properties.GroupVariableName;
                app.IndependentVariableName = gData.Properties.IndependentVariableName;
            end
            [~, idx] = ismember({app.GroupVariableName}, app.DataInfoTable.Data{:, 1});
            app.DataInfoTable.Data{idx, 2} = categorical({'<group variable>'});
            [~, idx] = ismember({app.IndependentVariableName}, app.DataInfoTable.Data{:, 1});
            app.DataInfoTable.Data{idx, 2} = categorical({'<independent variable>'});
            app.groupVariableNameChanged();
            
            % Reset/initialize SimFunction
            app.ResetSimFunction();

            
        end

        % Callback function: ComponentInfoTable, GroupDropDown, 
        % PercentageEditField, StopTimeEditField
        function ResetSimFunction(app, event)
            % The change requires a re-compilation of the model;
            % therefore a new SimFunction needs to be created.
            app.SimFunction = [];
            
            % Enable Simulate button to indicate that cached SimData is stale.
            app.SimulateButton.Enable = 'on';
            
            % Re-enable sensitivity option.
            app.UseSensitivityForAliasingScoreCheckBox.Enable = 'on';
            
            % Disable controls
            app.setControlsForSimulationRequired('off');
            
            % Change button text to indicate that simulation is required
            app.SimulateButton.Text = 'Simulate';
        end

        % Cell edit callback: DataInfoTable
        function DataInfoTableCellEdit(app, event)

            % Get table indices where the change happend
            rowIndex = event.Indices(1);
            colIndex = event.Indices(2);
            
            % A row that is not associated with a model component
            % cannot be selected to be included in the Aliasing Score
            if colIndex > 2 && ...
                    app.DataInfoTable.Data{rowIndex, colIndex} && ...
                    app.DataInfoTable.Data{rowIndex, 2} == categorical({'<unknown>'})
                app.DataInfoTable.Data{rowIndex, colIndex} = false;
                uialert(app.AliasingScoreApp, ['Associate data with a model ',...
                    'component by setting the Component Name before marking ',...
                    'it as dose or response to be included in the Aliasing Score.'], ...
                    'Response association required', 'Icon','warning');
                return;
            end
            
            % Check which column in the Data Info table changed:
            switch colIndex
                case 2
                    % A model response name, group variable or indep. variable changed:
                    % data columns that correspond to <unknown> model
                    % responses, group variable or independent variable cannot be 
                    % included in the Aliasing Score.
                    
                    if event.PreviousData == categorical({'<group variable>'})
                        uialert(app.AliasingScoreApp, ['The group variable cannot be changed to a response or independent variable. ', ...
                            'Please select another data column as group variable first.'], ...
                            'Selection conflict', 'Icon','warning');
                        app.DataInfoTable.Data{rowIndex, 2} = event.PreviousData;
                        return;
                    end
                    if event.PreviousData == categorical({'<independent variable>'})
                        uialert(app.AliasingScoreApp, ['The independent variable cannot be changed to a response or group variable. ', ...
                            'Please select another data column as independent variable first.'], ...
                            'Selection conflict', 'Icon','warning');
                        app.DataInfoTable.Data{rowIndex, 2} = event.PreviousData;
                        return;
                    end

                    if app.DataInfoTable.Data{rowIndex, 2} == categorical({'<unknown>'})
                        app.DataInfoTable.Data{rowIndex, 3} = false;
                        app.DataInfoTable.Data{rowIndex, 4} = false;
                    elseif app.DataInfoTable.Data{rowIndex, 2} == categorical({'<group variable>'})
                        [~, idx] = ismember({app.GroupVariableName}, app.DataInfoTable.Data{:, 1});
                        app.DataInfoTable.Data{idx, 2} = categorical({'<unknown>'});
                        app.GroupVariableName = app.DataInfoTable.Data{rowIndex, 1}{1};
                        app.groupVariableNameChanged();
                        app.DataInfoTable.Data{rowIndex, 3} = false;
                        app.DataInfoTable.Data{rowIndex, 4} = false;
                    elseif app.DataInfoTable.Data{rowIndex, 2} == categorical({'<independent variable>'})
                        [~, idx] = ismember({app.IndependentVariableName}, app.DataInfoTable.Data{:, 1});
                        app.DataInfoTable.Data{idx, 2} = categorical({'<unknown>'});
                        app.IndependentVariableName = app.DataInfoTable.Data{rowIndex, 1}{1};
                        app.ResetSimFunction();
                        app.DataInfoTable.Data{rowIndex, 3} = false;
                        app.DataInfoTable.Data{rowIndex, 4} = false;
                    end
                case 3 
                    % A data column indicator for being a dose changed:
                    % make sure a column is not simultaneously marked as
                    % dose and response.
                    if event.NewData && app.DataInfoTable.Data{rowIndex, 4}
                        app.DataInfoTable.Data{rowIndex, 4} = false;
                    end
                    if app.DataInfoTable.Data{rowIndex, 2} == categorical({'<group variable>'})
                        uialert(app.AliasingScoreApp, ['This data name is marked as group variable. Only model components can ', ...
                            'be used to define doses.'], 'Selection conflict', 'Icon','warning');
                        app.DataInfoTable.Data{rowIndex, colIndex} = event.PreviousData;
                        return;
                    end
                    if app.DataInfoTable.Data{rowIndex, 2} == categorical({'<independent variable>'})
                        uialert(app.AliasingScoreApp, ['This data name is marked as independent variable. Only model components can ', ...
                            'be used to define doses.'], 'Selection conflict', 'Icon','warning');
                        app.DataInfoTable.Data{rowIndex, colIndex} = event.PreviousData;
                        return;
                    end
                case 4
                    % A data column indicator for being a response changed:
                    % make sure a column is not simultaneously marked as
                    % dose and response.
                    if event.NewData && app.DataInfoTable.Data{rowIndex, 3}
                        app.DataInfoTable.Data{rowIndex, 3} = false;
                    end
                    if app.DataInfoTable.Data{rowIndex, 2} == categorical({'<group variable>'})
                        uialert(app.AliasingScoreApp, ['This data name is marked as group variable. Only model components can ', ...
                            'be included in the aliasing score.'], 'Selection conflict.', 'Icon','warning');
                        app.DataInfoTable.Data{rowIndex, colIndex} = event.PreviousData;
                        return;
                    end
                    if app.DataInfoTable.Data{rowIndex, 2} == categorical({'<independent variable>'})
                        uialert(app.AliasingScoreApp, ['This data name is marked as independent variable. Only model components can ', ...
                            'be included in the aliasing score.'], 'Selection conflict.', 'Icon','warning');
                        app.DataInfoTable.Data{rowIndex, colIndex} = event.PreviousData;
                        return;
                    end                    
            end
            
            % Update response drop down menu
            tfIncludeResponseInAliasingScore = app.DataInfoTable.Data{:,4};
            app.ResponseDropDown.Items = string(app.DataInfoTable.Data{tfIncludeResponseInAliasingScore,2});
            
            % Changes to the Data Info table require resetting the SimFunction
            app.ResetSimFunction();
        end

        % Button pushed function: IncludeAllInAliasingScoreButton
        function IncludeAllInAliasingScoreButtonPushed(app, event)
            
            % Check if there is a row that can be selected to be 
            % included in the Aliasing Score. 
            tfUnknownModelComponent = ismember(app.DataInfoTable.Data{:, 2}, ...
                categorical({'<unknown>', '<independent variable>', '<group variable>'}));
            tfDose = app.DataInfoTable.Data{:, 3};
            if all(tfUnknownModelComponent | tfDose)
                uialert(app.AliasingScoreApp, ['All model components are ', ...
                    'either marked as <unknown> or as being a dose. Associate ', ...
                    'data with a model component, or deselect a dose.'], ...
                    'Missing response information', 'Icon', 'warning');
                return;
            end
            
            % Mark all data columns, that are not marked as doses, 
            % as responses to be included in the Aliasing Score.
            tfDose = app.DataInfoTable.Data{:, 3};
            app.DataInfoTable.Data{~(tfUnknownModelComponent|tfDose), 4} = true;
            
            % Update response drop down menu
            tfIncludeResponseInAliasingScore = ...
                app.DataInfoTable.Data{:,4};
            app.ResponseDropDown.Items = cellstr(app.DataInfoTable.Data{tfIncludeResponseInAliasingScore,2});
            
            % Changes to the Data Info table require resetting the SimFunction
            app.ResetSimFunction();
        end

        % Button pushed function: DeselectAllResponsesButton
        function DeselectAllResponsesButtonPushed(app, event)
            % Deselect all data columns that marked as responses.
            app.DataInfoTable.Data{:, 4} = false;
            
            % Changes to the Data Info table require resetting the SimFunction
            app.ResetSimFunction();
        end

        % Value changing function: LowerBoundSlider
        function LowerBoundSliderValueChanging(app, event)
            % Make sure the upper bound slider is updated if necessary
            if event.Value > app.UpperBoundSlider.Value
                app.UpperBoundSlider.Value = event.Value;
            end 
        end

        % Value changing function: UpperBoundSlider
        function UpperBoundSliderValueChanging(app, event)
            % Make sure the lower bound slider is updated if necessary
            if event.Value < app.LowerBoundSlider.Value
                app.LowerBoundSlider.Value = event.Value;
            end 

        end

        % Value changed function: LowerBoundSlider, ResponseDropDown, 
        % ShowAliasingScoreForCurrentResponseOnlyCheckBox, 
        % UpperBoundSlider, UseDataTimesForAliasingScoreCheckBox, 
        % UseSensitivityForAliasingScoreCheckBox, ZoomCheckBox
        function RecomputeAliasingScoreAndPlotResults(app, event)
            % Do not plot if no cached simulation data / simfunction is available
            if isempty(app.SimFunction)
                return;
            end
            % Compute Aliasing Score and re-plot results
            aliasingScore = app.computeAliasingScore();
            app.plot(aliasingScore);
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            
            % Require selection of responses.
            tIncludeResponseInScore = app.DataInfoTable.Data{:,4};
            if ~any(tIncludeResponseInScore)
                uialert(app.AliasingScoreApp, [ ...
                    'No response selected in data information table. ', ...
                    'Select Responses in ''Include in Aliasing Score'' column in tab ', ...
                    '''Data Info''.'], 'Missing response selection');
                return;
            end
            
            % Require selection of parameters
            tfIncludeComponentInScore = app.ComponentInfoTable.Data{:,4};
            if ~any(tfIncludeComponentInScore)
                uialert(app.AliasingScoreApp, [ ...
                    'No parameter selected in parameter information table. ', ...
                    'Select Parameters in ''Include In Aliasing Score'' column in ', ...
                    'tab ''Component Info''.'], 'Missing component selection');
                return;
            end
            
            % Update sliders for current stop time.
            newStopTime = app.StopTimeEditField.Value;
            app.LowerBoundSlider.Limits = [0, newStopTime];
            app.UpperBoundSlider.Limits = [0, newStopTime];
            if newStopTime < app.LowerBoundSlider.Value
                app.LowerBoundSlider.Value = newStopTime;
            end
            if newStopTime < app.UpperBoundSlider.Value
                app.UpperBoundSlider.Value = newStopTime;
            end
            
            % Disable Simulate button and analysis controls
            app.SimulateButton.Enable = 'off';
            app.SimulateButton.Text = 'Please Wait ...';
            app.UseSensitivityForAliasingScoreCheckBox.Enable = 'off';
            app.GroupDropDown.Enable = 'off';
            app.PercentageEditField.Enable = 'off';
            app.StopTimeEditField.Enable = 'off';
            app.ComponentInfoTable.Enable = 'off';
            app.DataInfoTable.Enable = 'off';
            app.IncludeAllInAliasingScoreButton.Enable = 'off';
            app.DeselectAllResponsesButton.Enable = 'off';
            pause(1e-6);
            
            try
            
                % Generate dosing information.
                [dosing, doseTargets] = app.getDoses();
                
                % Get qualified names of responses:
                responseNames = cellstr(app.DataInfoTable.Data{tIncludeResponseInScore,2});
                % Get names of all non-constant quantity components:
                componentNames = app.ComponentInfoTable.Data{:, 2};
                
                % Clear and re-initialize cached simulation data:
                app.CachedSimulationData = struct();
                app.CachedSimulationData.ComponentNames = componentNames(tfIncludeComponentInScore);
                app.CachedSimulationData.ComponentDiff  = nan(size(app.CachedSimulationData.ComponentNames));
                app.CachedSimulationData.ResponseNames  = responseNames;
                app.CachedSimulationData.StopTime       = app.StopTimeEditField.Value;
                app.CachedSimulationData.Simulations    = [];
                
                % Create SimFunction if necesary
                if isempty(app.SimFunction)
                    try
                        % Try simulating with sensitivities
                        app.SimFunction = createSimFunction(app.SimBiologyModel, ...
                            componentNames, responseNames, ...
                            doseTargets, 'SensitivityOutputs', responseNames, ...
                            'SensitivityInputs', app.CachedSimulationData.ComponentNames);
                        if app.UseSensitivityForAliasingScoreCheckBox.Value && ...
                                ~isa(app.SimFunction, 'SimBiology.function.SimFunctionSensitivity')
                            uialert(app.AliasingScoreApp, ['Model does not support ', ...
                                'sensitivity analysis, using finite differences to ', ...
                                'compute aliasing score. Please specify three or more ', ...
                                'samples for meaningful results.'], ...
                                'Sensitivity analysis not supported', 'Icon','warning');
                            % Disable checkbox to select complex-step sensitivities
                            app.UseSensitivityForAliasingScoreCheckBox.Value = false;
                            sensitivityAnalysisAvailable = false;
                        else
                            sensitivityAnalysisAvailable = true;
                        end
                    catch MException
                        if contains(MException.message, 'Sensitivity analysis cannot be done') || ...
                            contains(MException.message, 'Elements of the Outputs property')
                            % Try simulating without sensitivities
                            app.SimFunction = createSimFunction(app.SimBiologyModel, ...
                                componentNames, responseNames, doseTargets);
                            if app.UseSensitivityForAliasingScoreCheckBox.Value
                                uialert(app.AliasingScoreApp, ['Model does not support ', ...
                                    'sensitivity analysis, using finite differences to ', ...
                                    'compute aliasing score. Please specify three or more ', ...
                                    'samples for meaningful results.'], ...
                                    'Sensitivity analysis not supported', 'Icon','warning');
                            end
                            % Disable checkbox to select complex-step sensitivities
                            app.UseSensitivityForAliasingScoreCheckBox.Value = false;
                            sensitivityAnalysisAvailable = false;
                        else
                            throw(MException);
                        end
                    end
                    
                end
                
                % Run simulation and store results         
                app.evaluateSimFunction(dosing, tfIncludeComponentInScore);
                
                % Compute Aliasing Score
                aliasingScore = app.computeAliasingScore();
                
                % Update plots
                app.plot(aliasingScore);
            
            catch MException
                
                if isempty(app.SimFunction)
                    uialert(app.AliasingScoreApp, ['Creation of SimFunction issued '...
                                'the following error message: ', MException.message], ...
                                'Creation of SimFunction failed');
                else
                    uialert(app.AliasingScoreApp, ['Evaluation of SimFunction issued '...
                                'the following error message: ', MException.message], ...
                                'Evaluation of SimFunction failed');                    
                end
                
                app.ResetSimFunction();
                
                % Reset controls
                app.SimulateButton.Text = 'Simulate';
                app.GroupDropDown.Enable = 'on';
                app.PercentageEditField.Enable = 'on';
                app.StopTimeEditField.Enable = 'on';
                app.ComponentInfoTable.Enable = 'on';
                app.DataInfoTable.Enable = 'on';
                app.IncludeAllInAliasingScoreButton.Enable = 'on';
                app.DeselectAllResponsesButton.Enable = 'on';
                
                throw(MException);
                
            end
            
            % Enable controls
            app.setControlsForSimulationRequired('on');
            app.SimulateButton.Text = 'Simulation Completed';
            if sensitivityAnalysisAvailable
                app.UseSensitivityForAliasingScoreCheckBox.Enable = 'on';
            else
                app.UseSensitivityForAliasingScoreCheckBox.Enable = 'off';
            end
            app.GroupDropDown.Enable = 'on';
            app.PercentageEditField.Enable = 'on';
            app.StopTimeEditField.Enable = 'on';
            app.ComponentInfoTable.Enable = 'on';
            app.DataInfoTable.Enable = 'on';
            app.IncludeAllInAliasingScoreButton.Enable = 'on';
            app.DeselectAllResponsesButton.Enable = 'on';
        end

        % Callback function
        function NumberOfSamplesEditFieldValueChanged(app, event)
            value = app.NumberOfSamplesEditField.Value;
            if mod(value, 2) == 0
                uialert(app.AliasingScoreApp, ['The number of samples must ', ...
                    'be odd and greater than 0.'], 'Invalid number of samples');
                app.ScoreSamplesEditField.Value = event.PreviousValue;
                return;
            end
            app.ResetSimFunction();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AliasingScoreApp = uifigure;
            app.AliasingScoreApp.Name = 'Aliasing Score';
            app.TabGroup = uitabgroup(app.AliasingScoreApp);
            app.AliasingScoreTab = uitab(app.TabGroup);
            app.AliasingScoreTab.Title = 'Aliasing Score';
            app.AliasingScoreAxes = uiaxes(app.AliasingScoreTab);
            title(app.AliasingScoreAxes, 'Aliasing Score')
            app.AliasingScoreAxes.XTick = [];
            app.AliasingScoreAxes.XTickLabel = {'[ ]'};
            app.AliasingScoreAxes.YTick = [];
            app.SimulationAxes = uiaxes(app.AliasingScoreTab);
            title(app.SimulationAxes, 'Normalized Sensitivity')
            xlabel(app.SimulationAxes, 'Time')
            app.SimulationAxes.XTick = [];
            app.SimulationAxes.YTick = [];
            app.SimulateButton = uibutton(app.AliasingScoreTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.StopTimeEditFieldLabel = uilabel(app.AliasingScoreTab);
            app.StopTimeEditFieldLabel.Text = 'Stop Time';
            app.StopTimeEditField = uieditfield(app.AliasingScoreTab, 'numeric');
            app.StopTimeEditField.Limits = [0 Inf];
            app.StopTimeEditField.ValueChangedFcn = createCallbackFcn(app, @ResetSimFunction, true);
            app.StopTimeEditField.Value = 10;
            app.GroupDropDownLabel = uilabel(app.AliasingScoreTab);
            app.GroupDropDownLabel.Text = 'Group';
            app.GroupDropDown = uidropdown(app.AliasingScoreTab);
            app.GroupDropDown.Items = {};
            app.GroupDropDown.ValueChangedFcn = createCallbackFcn(app, @ResetSimFunction, true);
            app.GroupDropDown.Value = {};
            app.PercentageEditFieldLabel = uilabel(app.AliasingScoreTab);
            app.PercentageEditFieldLabel.Text = 'Percentage';
            app.PercentageEditField = uieditfield(app.AliasingScoreTab, 'numeric');
            app.PercentageEditField.LowerLimitInclusive = 'off';
            app.PercentageEditField.Limits = [0 1];
            app.PercentageEditField.ValueChangedFcn = createCallbackFcn(app, @ResetSimFunction, true);
            app.PercentageEditField.Value = 0.1;
            app.LowerBoundSlider = uislider(app.AliasingScoreTab);
            app.LowerBoundSlider.Limits = [0 10];
            app.LowerBoundSlider.MajorTicks = [];
            app.LowerBoundSlider.MajorTickLabels = {};
            app.LowerBoundSlider.ValueChangedFcn = createCallbackFcn(app, @RecomputeAliasingScoreAndPlotResults, true);
            app.LowerBoundSlider.ValueChangingFcn = createCallbackFcn(app, @LowerBoundSliderValueChanging, true);
            app.LowerBoundSlider.MinorTicks = [];
            app.UpperBoundSlider = uislider(app.AliasingScoreTab);
            app.UpperBoundSlider.Limits = [0 10];
            app.UpperBoundSlider.MajorTicks = [];
            app.UpperBoundSlider.MajorTickLabels = {};
            app.UpperBoundSlider.ValueChangedFcn = createCallbackFcn(app, @RecomputeAliasingScoreAndPlotResults, true);
            app.UpperBoundSlider.ValueChangingFcn = createCallbackFcn(app, @UpperBoundSliderValueChanging, true);
            app.UpperBoundSlider.MinorTicks = [];
            app.UpperBoundSlider.Value = 10;
            app.ResponseDropDownLabel = uilabel(app.AliasingScoreTab);
            app.ResponseDropDownLabel.Text = 'Response';
            app.ResponseDropDown = uidropdown(app.AliasingScoreTab);
            app.ResponseDropDown.Items = {};
            app.ResponseDropDown.ValueChangedFcn = createCallbackFcn(app, @RecomputeAliasingScoreAndPlotResults, true);
            app.ResponseDropDown.Value = {};
            app.ShowAliasingScoreForCurrentResponseOnlyCheckBox = uicheckbox(app.AliasingScoreTab);
            app.ShowAliasingScoreForCurrentResponseOnlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecomputeAliasingScoreAndPlotResults, true);
            app.ShowAliasingScoreForCurrentResponseOnlyCheckBox.Text = 'Show Aliasing Score For Current Response Only';
            app.UseDataTimesForAliasingScoreCheckBox = uicheckbox(app.AliasingScoreTab);
            app.UseDataTimesForAliasingScoreCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecomputeAliasingScoreAndPlotResults, true);
            app.UseDataTimesForAliasingScoreCheckBox.Text = 'Use Data Times For Aliasing Score';
            app.ZoomCheckBox = uicheckbox(app.AliasingScoreTab);
            app.ZoomCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecomputeAliasingScoreAndPlotResults, true);
            app.ZoomCheckBox.Text = 'Zoom';
            app.UseSensitivityForAliasingScoreCheckBox = uicheckbox(app.AliasingScoreTab);
            app.UseSensitivityForAliasingScoreCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecomputeAliasingScoreAndPlotResults, true);
            app.UseSensitivityForAliasingScoreCheckBox.Text = 'Use Sensitivity For Aliasing Score';
            app.UseSensitivityForAliasingScoreCheckBox.Value = true;
            app.ComponentInfoTab = uitab(app.TabGroup);
            app.ComponentInfoTab.Title = 'Component Info';
            app.ComponentInfoTable = uitable(app.ComponentInfoTab);
            app.ComponentInfoTable.ColumnName = {'Type'; 'Name'; 'Value'; 'Include In Aliasing Score'};
            app.ComponentInfoTable.RowName = {};
            app.ComponentInfoTable.ColumnEditable = [false false true true];
            app.ComponentInfoTable.CellEditCallback = createCallbackFcn(app, @ResetSimFunction, true);
            app.DataInfoTab = uitab(app.TabGroup);
            app.DataInfoTab.Title = 'Data Info';
            app.DataInfoTable = uitable(app.DataInfoTab);
            app.DataInfoTable.ColumnName = {'Data Name'; 'Data Association'; 'Defines Dose'; 'Include In Aliasing Score'};
            app.DataInfoTable.RowName = {};
            app.DataInfoTable.ColumnEditable = true;
            app.DataInfoTable.CellEditCallback = createCallbackFcn(app, @DataInfoTableCellEdit, true);
            app.IncludeAllInAliasingScoreButton = uibutton(app.DataInfoTab, 'push');
            app.IncludeAllInAliasingScoreButton.ButtonPushedFcn = createCallbackFcn(app, @IncludeAllInAliasingScoreButtonPushed, true);
            app.IncludeAllInAliasingScoreButton.Text = 'Include All In Aliasing Score';
            app.DeselectAllResponsesButton = uibutton(app.DataInfoTab, 'push');
            app.DeselectAllResponsesButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllResponsesButtonPushed, true);
            app.DeselectAllResponsesButton.Text = 'Deselect All Responses';
            app.DataTableTab = uitab(app.TabGroup);
            app.DataTableTab.Title = 'Data Table';
            app.DataTable = uitable(app.DataTableTab);
            app.DataTable.ColumnName = {'ID'; 'Time'; 'DependentVariable1'; 'DependentVariable2'; 'Dose'};
            app.DataTable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = AliasingScore(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AliasingScoreApp)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AliasingScoreApp)
        end
    end
end